classdef appPBE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        SignalGenerationTab       matlab.ui.container.Tab
        TriFreq                   matlab.ui.control.NumericEditField
        freqHzEditField_2Label    matlab.ui.control.Label
        varEditField              matlab.ui.control.NumericEditField
        varEditFieldLabel         matlab.ui.control.Label
        freq2HzEditField          matlab.ui.control.NumericEditField
        freq2HzEditFieldLabel     matlab.ui.control.Label
        InputSignalDropDown       matlab.ui.control.DropDown
        InputSignalDropDownLabel  matlab.ui.control.Label
        DurationsEditField        matlab.ui.control.NumericEditField
        DurationsEditFieldLabel   matlab.ui.control.Label
        freqHzEditField           matlab.ui.control.NumericEditField
        freqHzEditFieldLabel      matlab.ui.control.Label
        AmplitudeVEditField       matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel  matlab.ui.control.Label
        RUNButton                 matlab.ui.control.Button
        UIAxesTriFT               matlab.ui.control.UIAxes
        UIAxesTri                 matlab.ui.control.UIAxes
        UIAxesInputFT             matlab.ui.control.UIAxes
        UIAxesInput               matlab.ui.control.UIAxes
        SimulationTab             matlab.ui.container.Tab
        REditField                matlab.ui.control.NumericEditField
        REditFieldLabel           matlab.ui.control.Label
        CEditField                matlab.ui.control.NumericEditField
        CEditFieldLabel           matlab.ui.control.Label
        LEditField                matlab.ui.control.NumericEditField
        LEditFieldLabel           matlab.ui.control.Label
        GainEditField             matlab.ui.control.NumericEditField
        GainEditFieldLabel        matlab.ui.control.Label
        simRUNButton              matlab.ui.control.Button
        UIAxesOut                 matlab.ui.control.UIAxes
        UIAxesComp                matlab.ui.control.UIAxes
        UIAxesTr                  matlab.ui.control.UIAxes
        UIAxesIn                  matlab.ui.control.UIAxes
        UIAxesOutFT               matlab.ui.control.UIAxes
        UIAxesCompFT              matlab.ui.control.UIAxes
        UIAxesTrFT                matlab.ui.control.UIAxes
        UIAxesInFT                matlab.ui.control.UIAxes
        MeasurementTab            matlab.ui.container.Tab
        GainLabel                 matlab.ui.control.Label
        THDLabel                  matlab.ui.control.Label
        measureRUNButton          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x
        X
        t        
        f
        fs
        fo
        D
        F
        F2
    end
    
    methods (Access = private)
        
        function serra = dent_serra(~,A,fo,t)
            serra = 2*(A*mod(fo*t,1)-A/2);
        end
        
        function tr = triangular(app,A,fo,t)
                tr = 2*abs(dent_serra(app,A,fo,t))-A;
        end
        
        function s = sinusoide(~,A,fo,t)
                s = A*sin(2*pi*t*fo);
        end
    
        function tren = polsos(app,A,fo,t)
            a = sinusoide(app,A,fo,t);
            for i = 1: length(a)
                if(a(i)>0)
                    a(i) = 1;
                else
                    a(i)=-1;
                end
            end
            tren = a;
        end
            
        function s = soroll(~,var,t,n)
            s = var.*randn(n,length(t));
        end
            
        function c = chirp(~,f1,f2,A,t)
            frec = linspace(f1,f2,length(t));
            c = A*sin(2*pi*frec.*t);
        end
    
        function c = comparador(~,s1,s2)
            l = s2;
            for i = 1:length(s1)
                if(s1(i)>s2(i))
                    l(i) = 1;
                else
                    l(i) = -1;
                end    
            end
            c = l;
        end
        
        function senyal = power_amplifier(~,G,s)
            senyal = G*s;
        end
    
        function senyal = lpf(~,s,R,L,C,fs)
            LC = L*C;
            RC = R*C;
            b  = [1/LC 2/LC 1/LC];
            a = [(4*fs^2+2*fs/RC+1/LC) (2/LC-8*fs^2) (4*fs^2-2*fs/RC+1/LC)];
            senyal = filter(b,a,s);
        end
        
        function THD = thd(~,d)
            td = abs(fft(d));
            len = floor(length(td)/2);
            figure(1);
            plot( 0:len -1 , td(1:len) ); 
            
            [a0,f0] = max(td(1:floor(len/2)));
            num_har = floor(len / f0);
            p_har = 0;
            
            for i = 2:num_har      
                [ai,fi] = max( td( floor( (i) * f0 - 5 ) : floor( (i) * f0 + 5 ) ) );
                p_har = p_har + ai^2;
            end
           
            THD = sqrt(p_har)/a0;
        end

        function g = guany(~,x,y)
%             px = (x(1).*x(1))/length(x);
%             py = (y(1).*y(1))/length(y);
%             g = sqrt(py/px);
            mx = max(x);
            my = max(y);
            g = my/mx;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            opt = app.InputSignalDropDown.Value;
            
            A = app.AmplitudeVEditField.Value;
            app.f = app.freqHzEditField.Value;
            f2 = app.freq2HzEditField.Value;
            app.D = app.DurationsEditField.Value;
            var = app.varEditField.Value;
            app.fo = app.TriFreq.Value;
            N = 32;
            app.fs = (2*N)*app.fo;
            app.t = 0:(1/app.fs):app.D-(1/app.fs);
            n = 0:1:app.D*app.fs-(1/app.fs);
        
            switch opt
                case '1'
                    app.x = sinusoide(app,A,app.f,app.t);
                  
                case '2'
                    app.x = dent_serra(app,A,app.f,app.t);
                    
                case '3'
                    app.x = triangular(app,A,app.f,app.t);
                    
                case '4'
                    app.x = polsos(app,A,app.f,app.t);
                    
                case '5'
                    app.x = soroll(app,var,app.t);
                    
                case '6'
                    app.x = chirp(app,app.f,f2,A,app.t);
            end
            
            plot(app.UIAxesInput, app.t, app.x);
            X0 = fft(app.x);
            app.X = X0(1:round(app.D*app.fs/2));
            app.F = 0:1/round(app.fs*app.D):1/2-1/round(app.fs*app.D);

            plot(app.UIAxesInputFT, app.F, abs(app.X));
            
            tr = triangular(app,1,app.fo,app.t);
            TR0 = abs(fft(tr));
            TR = TR0(1:round(app.D*app.fs/2));
            plot(app.UIAxesTri, app.t, tr); 
            plot(app.UIAxesTriFT, app.F, TR);
        end

        % Value changed function: InputSignalDropDown
        function InputSignalDropDownValueChanged(app, event)
            value = app.InputSignalDropDown.Value;
            switch value
                case '5'
                    app.varEditField.Editable = true;
                    app.varEditField.Enable = true;
                    app.varEditField.Visible = true;
                    app.varEditFieldLabel.Visible  = true;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = false;
                    app.freqHzEditField.Enable = false;
                    app.freqHzEditField.Visible = false;
                    app.freqHzEditFieldLabel.Visible = false;
                case '6'
                    app.freq2HzEditField.Editable = true;
                    app.freq2HzEditField.Enable = true;
                    app.freq2HzEditField.Visible = true;
                    app.freq2HzEditFieldLabel.Visible = true;
                    app.freqHzEditField.Editable = true;
                    app.freqHzEditField.Enable = true;
                    app.freqHzEditField.Visible = true;
                    app.freqHzEditFieldLabel.Visible = true;
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                otherwise
                    app.varEditField.Editable = false;
                    app.varEditField.Enable = false;
                    app.varEditField.Visible = false;
                    app.varEditFieldLabel.Visible  = false;
                    app.freq2HzEditField.Editable = false;
                    app.freq2HzEditField.Enable = false;
                    app.freq2HzEditField.Visible = false;
                    app.freq2HzEditFieldLabel.Visible = false;
                    app.freqHzEditField.Editable = true;
                    app.freqHzEditField.Enable = true;
                    app.freqHzEditField.Visible = true;
                    app.freqHzEditFieldLabel.Visible = true;
            end
        end

        % Callback function
        function DisplayModeDropDownValueChanged(app, event)
            value = app.DisplayModeDropDown.Value;
            switch value
                case '1'
                    app.UIAxesIn.Visible = true;
                    app.UIAxesInFT.Visible = true;
                    app.UIAxesTr.Visible = false;
                    app.UIAxesTrFT.Visible = false;
                    app.UIAxesComp.Visible = false;
                    app.UIAxesCompFT.Visible = false;
                    app.UIAxesOut.Visible = true;
                    app.UIAxesOutFT.Visible = true;
                    app.UIAxesIn.Box
                case '2'
                    app.UIAxesIn.Visible = true;
                    app.UIAxesInFT.Visible = true;
                    app.UIAxesTr.Visible = true;
                    app.UIAxesTrFT.Visible = true;
                    app.UIAxesComp.Visible = true;
                    app.UIAxesCompFT.Visible = true;
                    app.UIAxesOut.Visible = true;
                    app.UIAxesOutFT.Visible = true;
            end
        end

        % Button pushed function: simRUNButton
        function simRUNButtonPushed(app, event)
            plot(app.UIAxesIn, app.t, app.x);
            plot(app.UIAxesInFT, app.F, abs(app.X));

            tr = triangular(app,1,app.fo,app.t);
            TR0 = fft(tr);
            TR = TR0(1:round(app.D*app.fs/2));
            
            plot(app.UIAxesTr,app.t,tr);
            plot(app.UIAxesTrFT, app.F, abs(TR));
            
            c = comparador(app,app.x,tr);
            C0 = fft(c);
            C = C0(1:round(app.D*app.fs/2));
            plot(app.UIAxesComp, app.t, c);
            plot(app.UIAxesCompFT, app.F, abs(C));
            
            G = app.GainEditField.Value;
            amp = power_amplifier(app,G,c);
            
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            R = app.REditField.Value;

            y = lpf(app,amp,R,L,C,app.fs);
            Y0 = fft(y);
            Y = Y0(1:round(app.D*app.fs/2));
            plot(app.UIAxesOut, app.t, y);
            plot(app.UIAxesOutFT, app.F, abs(Y));
            
        end

        % Button pushed function: measureRUNButton
        function measureRUNButtonPushed(app, event)
            Fs           = 44100;   
            duration     = 1;      
            Nbits        = 16;
            
            F_thd = 1000;                        
            samples = duration*Fs;
            t_thd = 0:(1/Fs):duration-(1/Fs);

            y(:,1)  = sin(2*pi*F_thd*t_thd);                  
            y(:,2)  = y(:,1);
            
            player = audioplayer(y, Fs, Nbits);
            recorder = audiorecorder(Fs, Nbits, 1);
            record(recorder,duration);
            playblocking(player);
            stop(recorder);
            signal = getaudiodata(recorder, 'single');
            [N,M]=size(signal);

            G = app.guany(y(:,1),signal);
            THD = app.thd(signal);
            app.GainLabel.Text = "Gain: " + G;
            app.THDLabel.Text = "THD: " + THD;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalGenerationTab = uitab(app.TabGroup);
            app.SignalGenerationTab.Title = 'Signal Generation';
            app.UIAxesInput = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesInput, 'Input Signal')
            xlabel(app.UIAxesInput, 't(s)')
            app.UIAxesInputFT = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesInputFT, 'F.T.')
            xlabel(app.UIAxesInputFT, 'F')
            app.UIAxesTri = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesTri, 'Triangular Signal')
            xlabel(app.UIAxesTri, 't(s)')
            app.UIAxesTriFT = uiaxes(app.SignalGenerationTab);
            title(app.UIAxesTriFT, 'F.T.')
            xlabel(app.UIAxesTriFT, 'F')
            app.RUNButton = uibutton(app.SignalGenerationTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.AmplitudeVEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.AmplitudeVEditField.Limits = [0 1];
            app.AmplitudeVEditField.Value = 1;
            app.freqHzEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.freqHzEditFieldLabel.Text = 'freq (Hz)';
            app.freqHzEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.freqHzEditField.Limits = [0 Inf];
            app.freqHzEditField.Value = 20000;
            app.DurationsEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.DurationsEditField.Limits = [0 Inf];
            app.DurationsEditField.Value = 0.003;
            app.InputSignalDropDownLabel = uilabel(app.SignalGenerationTab);
            app.InputSignalDropDownLabel.Text = 'Input Signal';
            app.InputSignalDropDown = uidropdown(app.SignalGenerationTab);
            app.InputSignalDropDown.Items = {'sinusoide', 'saw teeth', 'triangular', 'rectangular', 'noise', 'chirp'};
            app.InputSignalDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.InputSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignalDropDownValueChanged, true);
            app.InputSignalDropDown.Value = '1';
            app.freq2HzEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.freq2HzEditFieldLabel.Visible = 'off';
            app.freq2HzEditFieldLabel.Text = 'freq2 (Hz)';
            app.freq2HzEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.freq2HzEditField.Limits = [0 Inf];
            app.freq2HzEditField.Editable = 'off';
            app.freq2HzEditField.Visible = 'off';
            app.freq2HzEditField.Value = 10;
            app.varEditFieldLabel = uilabel(app.SignalGenerationTab);
            app.varEditFieldLabel.Visible = 'off';
            app.varEditFieldLabel.Text = 'var';
            app.varEditField = uieditfield(app.SignalGenerationTab, 'numeric');
            app.varEditField.LowerLimitInclusive = 'off';
            app.varEditField.Limits = [0 Inf];
            app.varEditField.Editable = 'off';
            app.varEditField.Visible = 'off';
            app.varEditField.Value = 1;
            app.freqHzEditField_2Label = uilabel(app.SignalGenerationTab);
            app.freqHzEditField_2Label.Text = 'freq (Hz)';
            app.TriFreq = uieditfield(app.SignalGenerationTab, 'numeric');
            app.TriFreq.Limits = [0 Inf];
            app.TriFreq.Value = 500000;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxesInFT = uiaxes(app.SimulationTab);
            title(app.UIAxesInFT, 'Input Signal F.T.')
            xlabel(app.UIAxesInFT, 'F')
            app.UIAxesTrFT = uiaxes(app.SimulationTab);
            title(app.UIAxesTrFT, 'Triangular Signal F.T.')
            xlabel(app.UIAxesTrFT, 'F')
            app.UIAxesCompFT = uiaxes(app.SimulationTab);
            title(app.UIAxesCompFT, 'PMW F.T.')
            xlabel(app.UIAxesCompFT, 'F')
            app.UIAxesOutFT = uiaxes(app.SimulationTab);
            title(app.UIAxesOutFT, 'Output Signal F.T.')
            xlabel(app.UIAxesOutFT, 'F')
            app.UIAxesIn = uiaxes(app.SimulationTab);
            title(app.UIAxesIn, 'Input Signal')
            xlabel(app.UIAxesIn, 't(s)')
            app.UIAxesTr = uiaxes(app.SimulationTab);
            title(app.UIAxesTr, 'Triangular Signal')
            xlabel(app.UIAxesTr, 't(s)')
            app.UIAxesComp = uiaxes(app.SimulationTab);
            title(app.UIAxesComp, 'PMW')
            xlabel(app.UIAxesComp, 't(s)')
            app.UIAxesOut = uiaxes(app.SimulationTab);
            title(app.UIAxesOut, 'Output Signal')
            xlabel(app.UIAxesOut, 't(s)')
            app.simRUNButton = uibutton(app.SimulationTab, 'push');
            app.simRUNButton.ButtonPushedFcn = createCallbackFcn(app, @simRUNButtonPushed, true);
            app.simRUNButton.Text = 'RUN';
            app.GainEditFieldLabel = uilabel(app.SimulationTab);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.SimulationTab, 'numeric');
            app.GainEditField.Value = 5;
            app.LEditFieldLabel = uilabel(app.SimulationTab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SimulationTab, 'numeric');
            app.LEditField.Value = 2.2e-05;
            app.CEditFieldLabel = uilabel(app.SimulationTab);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.SimulationTab, 'numeric');
            app.CEditField.Value = 4.7e-07;
            app.REditFieldLabel = uilabel(app.SimulationTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SimulationTab, 'numeric');
            app.REditField.Value = 5;
            app.MeasurementTab = uitab(app.TabGroup);
            app.MeasurementTab.Title = 'Measurement';
            app.UIAxes = uiaxes(app.MeasurementTab);
            title(app.UIAxes, 'FRECUENCY RESPONSE')
            app.measureRUNButton = uibutton(app.MeasurementTab, 'push');
            app.measureRUNButton.ButtonPushedFcn = createCallbackFcn(app, @measureRUNButtonPushed, true);
            app.measureRUNButton.Text = 'RUN';
            app.THDLabel = uilabel(app.MeasurementTab);
            app.THDLabel.Text = 'THD:';
            app.GainLabel = uilabel(app.MeasurementTab);
            app.GainLabel.Text = 'Gain:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appPBE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end